classdef DragonArmGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure  matlab.ui.Figure
        CONNECTButton         matlab.ui.control.Button
        ValuePanel_2          matlab.ui.container.Panel
        SAVEButton_3          matlab.ui.control.Button
        GOButton_4            matlab.ui.control.Button
        Label_3               matlab.ui.control.Label
        SAVEButton_2          matlab.ui.control.Button
        GOButton_3            matlab.ui.control.Button
        Label_2               matlab.ui.control.Label
        SAVEButton            matlab.ui.control.Button
        GOButton_2            matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        SliderPanel           matlab.ui.container.Panel
        Joint5Slider          matlab.ui.control.Slider
        Joint5SliderLabel     matlab.ui.control.Label
        Joint4Slider          matlab.ui.control.Slider
        Joint4SliderLabel     matlab.ui.control.Label
        J5ValueEditField      matlab.ui.control.NumericEditField
        J4ValueEditField      matlab.ui.control.NumericEditField
        HOMEButton            matlab.ui.control.Button
        J3ValueEditField      matlab.ui.control.NumericEditField
        J2ValueEditField      matlab.ui.control.NumericEditField
        J1ValueEditField      matlab.ui.control.NumericEditField
        Joint3Slider          matlab.ui.control.Slider
        Joint3SliderLabel     matlab.ui.control.Label
        Joint2Slider          matlab.ui.control.Slider
        Joint2SliderLabel     matlab.ui.control.Label
        Joint1Slider          matlab.ui.control.Slider
        Joint1SliderLabel     matlab.ui.control.Label
        axes_arm              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        link5Patch
        link5Vertices

        jointAngles = [0 -90 0 0 0];

        savedAngles1 = [0 0 0 0 0];
        savedAngles2 = [0 0 0 0 0];
        savedAngles3 = [0 0 0 0 0];

        ControllerClient
        ControllerTimer
        ControllerBuffer = ''
    
        SelectedJoint = 1
        linkPatches
    
        PreviousButtons = []
        ControllerDeadzone = 0.15
        ControllerSpeed = 10  % radians/second
        ControllerFineScale = 0.2
    end
    
    methods (Access = private)
        
        function updateArm(app)

            % Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(50, 60, -90, app.jointAngles(1));
            A2 = create_A_matrix(100, 0, 0, app.jointAngles(2));
            A3 = create_A_matrix(0, 0, -90, app.jointAngles(3));
            A4 = create_A_matrix(0, 40, 90, app.jointAngles(4));
            A5 = create_A_matrix(0, 0, -90, app.jointAngles(5));
        
            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            T0_4 = A1 * A2 * A3 * A4;
            T0_5 = A1 * A2 * A3 * A4 * A5;
            
            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link4VerticesWRTground = T0_4 * app.link4Vertices;
            link5VerticesWRTground = T0_5 * app.link5Vertices;
            
            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
            set(app.link5Patch,'Vertices', link5VerticesWRTground(1:3,:)');
            

            app.Label.Text = string(app.savedAngles1(1) + ", " + app.savedAngles1(2) + ", " + app.savedAngles1(3) + ", " + app.savedAngles1(4) + ", " + app.savedAngles1(5));
            app.Label_2.Text = string(app.savedAngles2(1) + ", " + app.savedAngles2(2) + ", " + app.savedAngles2(3) + ", " + app.savedAngles2(4) + ", " + app.savedAngles2(5));
            app.Label_3.Text = string(app.savedAngles3(1) + ", " + app.savedAngles3(2) + ", " + app.savedAngles3(3) + ", " + app.savedAngles3(4) + ", " + app.savedAngles3(5));

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointAngles(4);
            app.Joint5Slider.Value = app.jointAngles(5);

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.J4ValueEditField.Value = app.jointAngles(4);
            app.J5ValueEditField.Value = app.jointAngles(5);

        end

        
        function ControllerPoll(app)

            % Read any TCP data waiting in MATLAB.
            if isempty(app.ControllerClient) || ...
                    ~isvalid(app.ControllerClient)
                return;
            end
    
            n = app.ControllerClient.NumBytesAvailable;
            if n == 0
                return;
            end
    
            incoming = read(app.ControllerClient, n, "char");
            app.ControllerBuffer = [app.ControllerBuffer, incoming];
    
            % Process complete newline-delimited JSON messages.
            while true
                k = find(app.ControllerBuffer == newline, 1);
                if isempty(k)
                    break;
                end
    
                line = app.ControllerBuffer(1:k-1);
                app.ControllerBuffer = app.ControllerBuffer(k+1:end);
    
                if isempty(line)
                    continue;
                end
    
                try
                    data = jsondecode(line);
                    app.ApplyControllerInput(data);
                catch ME
                    disp("Controller packet error: " + ME.message);
                end
            end
        end
    
    
        function ApplyControllerInput(app, data)
    
            disp("Controller callback running");

            disp(data.axes);
            disp(data.buttons);
                
            axes = data.axes(:)';
            buttons = data.buttons(:)';
    
            % Replace these indices after testing your controller.
            x = 0;
            if numel(axes) >= 1
                x = axes(1);
            end
    
            % Remove small joystick/trackpad drift.
            if abs(x) < app.ControllerDeadzone
                x = 0;
            else
                x = sign(x) * ...
                    (abs(x) - app.ControllerDeadzone) / ...
                    (1 - app.ControllerDeadzone);
            end
    
            % Detect newly pressed buttons, not buttons held down.
            if isempty(app.PreviousButtons)
                app.PreviousButtons = zeros(size(buttons));
            end
    
            old = zeros(size(buttons));
            n = min(numel(old), numel(app.PreviousButtons));
            old(1:n) = app.PreviousButtons(1:n);
    
            pressed = buttons & ~old;
    
            % Example mappings: verify button indices first.
            if numel(pressed) >= 1 && pressed(1)
                app.SelectedJoint = mod(app.SelectedJoint, numel(app.jointAngles)) + 1;

                app.HighlightSelectedLink();
            end
    
            if numel(pressed) >= 3 && pressed(3)
                app.SelectedJoint = mod(app.SelectedJoint - 2, numel(app.jointAngles)) + 1;

                app.HighlightSelectedLink();
            end
    
            % Example: button index 2 resets the selected joint.
            if numel(pressed) >= 2 && pressed(2)
                if ~isempty(app.JointLimits)
                    % Use midpoint as an example home position.
                    j = app.SelectedJoint;
                    app.jointAngles(j) = mean(app.JointLimits(j,:));
                end
            end
    
            % Example: trigger held enables fine control.
            fine = false;
            if numel(buttons) >= 5
                fine = buttons(5) ~= 0;
            end
    
            speed = app.ControllerSpeed;
            if fine
                speed = speed * app.ControllerFineScale;
            end
    
            % Integrate angular velocity over elapsed time.
            dt = 0.02;
            j = app.SelectedJoint;
            app.jointAngles(j) = app.jointAngles(j) + x * speed * dt;
    
            app.PreviousButtons = buttons;
    
            app.updateArm()
        end

        function HighlightSelectedLink(app)

            % Default color for unselected links
            defaultColor = [0.2, 0.2, 0.2];
        
            % Highlight color for the selected link
            selectedColor = [1.0, 0.75, 0.0];
        
            for i = 1:numel(app.linkPatches)
                if i == app.SelectedJoint
                    app.linkPatches(i).FaceColor = selectedColor;
                    app.linkPatches(i).EdgeColor = 'k';
                    app.linkPatches(i).LineWidth = 1.5;
                else
                    app.linkPatches(i).FaceColor = defaultColor;
                    app.linkPatches(i).EdgeColor = 'k';
                    app.linkPatches(i).LineWidth = 0.5;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-100 100];
            % app.axes_arm.YLim = [-150 150];
            % app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("LowL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("LowL1.stl", app.axes_arm, [.2 .2 .2]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("LowL2.stl", app.axes_arm, [.2 .2 .2]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
        
            app.link3Patch = makeStlLink("LowL5.stl", app.axes_arm, [.2 .2 .2]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
        
            app.link4Patch = makeStlLink("LowL6.stl", app.axes_arm, [.2 .2 .2]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));
        
            app.link5Patch = makeStlLink("LowL7.stl", app.axes_arm, [.2 .2 .2]);
            app.link5Vertices = get(app.link5Patch, 'Vertices')';
            app.link5Vertices(4,:) = ones(1, size(app.link5Vertices,2));

            app.linkPatches = [app.link1Patch, app.link2Patch, app.link3Patch, app.link4Patch, app.link5Patch];
            
            updateArm(app);

        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;

            app.jointAngles(1) = changingValue;
            app.updateArm()
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;

            app.jointAngles(2) = changingValue;
            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;

            app.jointAngles(3) = changingValue;
            app.updateArm()
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;

            app.jointAngles(4) = changingValue;
            app.updateArm()
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingValue = event.Value;

            app.jointAngles(5) = changingValue;
            app.updateArm()
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.jointAngles = [0, -90, 0, 0, 0];

            app.updateArm()
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.savedAngles1 = app.jointAngles;
            app.updateArm()
        end

        % Button pushed function: GOButton_2
        function GOButton_2Pushed(app, event)
            app.jointAngles = app.savedAngles1;
            app.updateArm()
        end

        % Button pushed function: SAVEButton_2
        function SAVEButton_2Pushed(app, event)
            app.savedAngles2 = app.jointAngles;
            app.updateArm()
        end

        % Button pushed function: GOButton_3
        function GOButton_3Pushed(app, event)
            app.jointAngles = app.savedAngles2;
            app.updateArm()
        end

        % Button pushed function: SAVEButton_3
        function SAVEButton_3Pushed(app, event)
            app.savedAngles3 = app.jointAngles;
            app.updateArm()
        end

        % Button pushed function: GOButton_4
        function GOButton_4Pushed(app, event)
            app.jointAngles = app.savedAngles3;
            app.updateArm()
        end

        % Value changed function: J1ValueEditField
        function J1ValueEditFieldValueChanged(app, event)
            app.jointAngles(1) = app.J1ValueEditField.Value;
            app.updateArm()
        end

        % Value changed function: J2ValueEditField
        function J2ValueEditFieldValueChanged(app, event)
            app.jointAngles(2) = app.J2ValueEditField.Value;
            app.updateArm()
        end

        % Value changed function: J3ValueEditField
        function J3ValueEditFieldValueChanged(app, event)
            app.jointAngles(3) = app.J3ValueEditField.Value;
            app.updateArm()
        end

        % Value changed function: J4ValueEditField
        function J4ValueEditFieldValueChanged(app, event)
            app.jointAngles(4) = app.J4ValueEditField.Value;
            app.updateArm()
        end

        % Value changed function: J5ValueEditField
        function J5ValueEditFieldValueChanged(app, event)
            app.jointAngles(5) = app.J5ValueEditField.Value;
            app.updateArm()
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            % Python must already be running and listening on port 5005.
            app.ControllerClient = tcpclient( ...
                "127.0.0.1", 5005, "Timeout", 2);
        
            app.ControllerBuffer = '';
            app.PreviousButtons = [];
        
            app.ControllerTimer = timer( ...
                "ExecutionMode", "fixedSpacing", ...
                "Period", 0.02, ...
                "BusyMode", "drop", ...
                "TimerFcn", @(~,~) app.ControllerPoll());
        
            app.HighlightSelectedLink();

            start(app.ControllerTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'Adeept Arm GUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.SliderPanel = uipanel(app.AdeeptArmGUIUIFigure);
            app.SliderPanel.Title = 'Sliders';
            app.Joint1SliderLabel = uilabel(app.SliderPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.SliderPanel);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.MajorTicks = [];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = [];
            app.Joint2SliderLabel = uilabel(app.SliderPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.SliderPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.MajorTicks = [];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint2Slider.MinorTicks = [];
            app.Joint3SliderLabel = uilabel(app.SliderPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.SliderPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.MajorTicks = [];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint3Slider.MinorTicks = [];
            app.J1ValueEditField = uieditfield(app.SliderPanel, 'numeric');
            app.J1ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J1ValueEditFieldValueChanged, true);
            app.J2ValueEditField = uieditfield(app.SliderPanel, 'numeric');
            app.J2ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J2ValueEditFieldValueChanged, true);
            app.J3ValueEditField = uieditfield(app.SliderPanel, 'numeric');
            app.J3ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J3ValueEditFieldValueChanged, true);
            app.HOMEButton = uibutton(app.SliderPanel, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.J4ValueEditField = uieditfield(app.SliderPanel, 'numeric');
            app.J4ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J4ValueEditFieldValueChanged, true);
            app.J5ValueEditField = uieditfield(app.SliderPanel, 'numeric');
            app.J5ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J5ValueEditFieldValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.SliderPanel);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.SliderPanel);
            app.Joint4Slider.Limits = [-180 180];
            app.Joint4Slider.MajorTicks = [];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint4Slider.MinorTicks = [];
            app.Joint5SliderLabel = uilabel(app.SliderPanel);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.SliderPanel);
            app.Joint5Slider.Limits = [-90 90];
            app.Joint5Slider.MajorTicks = [];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint5Slider.MinorTicks = [];
            app.ValuePanel_2 = uipanel(app.AdeeptArmGUIUIFigure);
            app.ValuePanel_2.Title = 'Save Position';
            app.Label = uilabel(app.ValuePanel_2);
            app.GOButton_2 = uibutton(app.ValuePanel_2, 'push');
            app.GOButton_2.ButtonPushedFcn = createCallbackFcn(app, @GOButton_2Pushed, true);
            app.GOButton_2.Text = 'GO';
            app.SAVEButton = uibutton(app.ValuePanel_2, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.Label_2 = uilabel(app.ValuePanel_2);
            app.GOButton_3 = uibutton(app.ValuePanel_2, 'push');
            app.GOButton_3.ButtonPushedFcn = createCallbackFcn(app, @GOButton_3Pushed, true);
            app.GOButton_3.Text = 'GO';
            app.SAVEButton_2 = uibutton(app.ValuePanel_2, 'push');
            app.SAVEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_2Pushed, true);
            app.SAVEButton_2.Text = 'SAVE';
            app.Label_3 = uilabel(app.ValuePanel_2);
            app.GOButton_4 = uibutton(app.ValuePanel_2, 'push');
            app.GOButton_4.ButtonPushedFcn = createCallbackFcn(app, @GOButton_4Pushed, true);
            app.GOButton_4.Text = 'GO';
            app.SAVEButton_3 = uibutton(app.ValuePanel_2, 'push');
            app.SAVEButton_3.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_3Pushed, true);
            app.SAVEButton_3.Text = 'SAVE';
            app.CONNECTButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DragonArmGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end