classdef Exam1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SerialResponseLabel       matlab.ui.control.Label
        PeriodmsEditField         matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel    matlab.ui.control.Label
        NumFlashesEditField       matlab.ui.control.NumericEditField
        NumFlashesEditFieldLabel  matlab.ui.control.Label
        FLASHButton               matlab.ui.control.Button
        LEDOFFButton              matlab.ui.control.Button
        LEDONButton               matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function sendCommand(app, command)
            response = webread("http://fulksdm-pi5.rose-hulman.edu:8080/api/" + command);
            app.SerialResponseLabel.Text = response;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LEDONButton
        function LEDONButtonPushed(app, event)
            app.sendCommand("led/ON")
        end

        % Button pushed function: LEDOFFButton
        function LEDOFFButtonPushed(app, event)
            app.sendCommand("led/OFF")
        end

        % Button pushed function: FLASHButton
        function FLASHButtonPushed(app, event)
            app.sendCommand("flash/" + app.NumFlashesEditField.Value + "/" + app.PeriodmsEditField.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDONButton = uibutton(app.UIFigure, 'push');
            app.LEDONButton.ButtonPushedFcn = createCallbackFcn(app, @LEDONButtonPushed, true);
            app.LEDONButton.Text = 'LED ON';
            app.LEDOFFButton = uibutton(app.UIFigure, 'push');
            app.LEDOFFButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOFFButtonPushed, true);
            app.LEDOFFButton.Text = 'LED OFF';
            app.FLASHButton = uibutton(app.UIFigure, 'push');
            app.FLASHButton.ButtonPushedFcn = createCallbackFcn(app, @FLASHButtonPushed, true);
            app.FLASHButton.Text = 'FLASH';
            app.NumFlashesEditFieldLabel = uilabel(app.UIFigure);
            app.NumFlashesEditFieldLabel.Text = 'Num Flashes';
            app.NumFlashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumFlashesEditField.Limits = [0 Inf];
            app.NumFlashesEditField.Value = 5;
            app.PeriodmsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodmsEditField.Limits = [0 Inf];
            app.PeriodmsEditField.Value = 1000;
            app.SerialResponseLabel = uilabel(app.UIFigure);
            app.SerialResponseLabel.Text = 'Serial Response';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end