classdef ChatroomGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LastMessageLabel       matlab.ui.control.Label
        PayloadEditField       matlab.ui.control.EditField
        PayloadEditFieldLabel  matlab.ui.control.Label
        SendButton             matlab.ui.control.Button
        TypeEditField          matlab.ui.control.EditField
        TypeEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)

        mqClient

    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            if ~isempty(messageStruct.payload)
                messagePayload = messageStruct.payload;
            end

            app.LastMessageLabel.Text = messagePayload;

        end

        function sendMessage(app, messageType, messagePayload)

            messageStruct.type = messageType;
            messageStruct.payload = messagePayload;
            messageJson = jsonencode(messageStruct);

            app.mqClient.write("chat", messageJson);
            fprintf("Sent: %s\n", messageJson);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");

            app.mqClient.subscribe("chat", "Callback", @app.myCallback);

        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            
            app.sendMessage(app.TypeEditField.Value, app.PayloadEditField.Value);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TypeEditFieldLabel = uilabel(app.UIFigure);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.UIFigure, 'text');
            app.TypeEditField.Value = 'chat';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.PayloadEditFieldLabel = uilabel(app.UIFigure);
            app.PayloadEditFieldLabel.Text = 'Payload';
            app.PayloadEditField = uieditfield(app.UIFigure, 'text');
            app.LastMessageLabel = uilabel(app.UIFigure);
            app.LastMessageLabel.Text = 'Last Message Label';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChatroomGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end