classdef LEDGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SendLEDsButton     matlab.ui.control.Button
        CounterLabel       matlab.ui.control.Label
        GreenSwitch        matlab.ui.control.ToggleSwitch
        GreenSwitchLabel   matlab.ui.control.Label
        YellowSwitch       matlab.ui.control.ToggleSwitch
        YellowSwitchLabel  matlab.ui.control.Label
        RedSwitch          matlab.ui.control.ToggleSwitch
        RedSwitchLabel     matlab.ui.control.Label
        GreenOffButton     matlab.ui.control.Button
        GreenOnButton      matlab.ui.control.Button
        YellowOffButton    matlab.ui.control.Button
        YellowOnButton     matlab.ui.control.Button
        RedOffButton       matlab.ui.control.Button
        RedOnButton        matlab.ui.control.Button
    end

    
    properties (Access = private)

        mqClient
        counter

    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            if messageType == "button"
                app.counter = app.counter + 1;
                app.CounterLabel.Text = sprintf("Counter = %d", app.counter);
            end

            if messageType == "reset"
                app.counter = 0;
                app.CounterLabel.Text = sprintf("Counter = %d", app.counter);
            end

        end

        function sendMessage(app, messageType, messagePayload)

            messageStruct.type = messageType;

            if ~isempty(messagePayload)
                messageStruct.payload = messagePayload;
            end

            messageJson = jsonencode(messageStruct);

            app.mqClient.write("me435/fulksdm/to_pi", messageJson);
            fprintf("Sent: %s\n", messageJson);

        end

        function value = convertLED(app, object)
            
            state = object.Value;
            if state == "On"
                value = 1;
            else
                value = 0;
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");

            app.mqClient.subscribe("me435/fulksdm/#", "Callback", @app.myCallback);

        end

        % Button pushed function: RedOnButton
        function RedOnButtonPushed(app, event)
            
            app.sendMessage("red", "on")

        end

        % Button pushed function: RedOffButton
        function RedOffButtonPushed(app, event)
            
            app.sendMessage("red", "off")

        end

        % Button pushed function: YellowOnButton
        function YellowOnButtonPushed(app, event)
            
            app.sendMessage("yellow", 1)

        end

        % Button pushed function: YellowOffButton
        function YellowOffButtonPushed(app, event)
            
            app.sendMessage("yellow", 0)

        end

        % Button pushed function: GreenOnButton
        function GreenOnButtonPushed(app, event)
            
            app.sendMessage("green", "wee")

        end

        % Button pushed function: GreenOffButton
        function GreenOffButtonPushed(app, event)
            
            app.sendMessage("green", missing)

        end

        % Button pushed function: SendLEDsButton
        function SendLEDsButtonPushed(app, event)
            
            redValue = app.convertLED(app.RedSwitch);
            yellowValue = app.convertLED(app.YellowSwitch);
            greenValue = app.convertLED(app.GreenSwitch);
            app.sendMessage("leds", [redValue, yellowValue, greenValue])

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedOnButton = uibutton(app.UIFigure, 'push');
            app.RedOnButton.ButtonPushedFcn = createCallbackFcn(app, @RedOnButtonPushed, true);
            app.RedOnButton.Text = 'Red On';
            app.RedOffButton = uibutton(app.UIFigure, 'push');
            app.RedOffButton.ButtonPushedFcn = createCallbackFcn(app, @RedOffButtonPushed, true);
            app.RedOffButton.Text = 'Red Off';
            app.YellowOnButton = uibutton(app.UIFigure, 'push');
            app.YellowOnButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOnButtonPushed, true);
            app.YellowOnButton.Text = 'Yellow On';
            app.YellowOffButton = uibutton(app.UIFigure, 'push');
            app.YellowOffButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOffButtonPushed, true);
            app.YellowOffButton.Text = 'Yellow Off';
            app.GreenOnButton = uibutton(app.UIFigure, 'push');
            app.GreenOnButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOnButtonPushed, true);
            app.GreenOnButton.Text = 'Green On';
            app.GreenOffButton = uibutton(app.UIFigure, 'push');
            app.GreenOffButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOffButtonPushed, true);
            app.GreenOffButton.Text = 'Green Off';
            app.RedSwitchLabel = uilabel(app.UIFigure);
            app.RedSwitchLabel.Text = 'Red';
            app.RedSwitch = uiswitch(app.UIFigure, 'toggle');
            app.YellowSwitchLabel = uilabel(app.UIFigure);
            app.YellowSwitchLabel.Text = 'Yellow';
            app.YellowSwitch = uiswitch(app.UIFigure, 'toggle');
            app.GreenSwitchLabel = uilabel(app.UIFigure);
            app.GreenSwitchLabel.Text = 'Green';
            app.GreenSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CounterLabel = uilabel(app.UIFigure);
            app.CounterLabel.Text = 'Counter = 0';
            app.SendLEDsButton = uibutton(app.UIFigure, 'push');
            app.SendLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SendLEDsButtonPushed, true);
            app.SendLEDsButton.Text = 'Send LEDs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LEDGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end