classdef GoldGuesser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GoldGuesserUIFigure  matlab.ui.Figure
        ImageGold            matlab.ui.control.Image
        ImageLeprecaun       matlab.ui.control.Image
        LabelPercentValue    matlab.ui.control.Label
        LabelSuccessValue    matlab.ui.control.Label
        LabelAttemptsValue   matlab.ui.control.Label
        PercentLabel         matlab.ui.control.Label
        SuccessLabel         matlab.ui.control.Label
        AttemptsLabel        matlab.ui.control.Label
        Button_3             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_1             matlab.ui.control.Button
    end

    
    properties (Access = private)
        LeprecaunPositions
        GoldPositions
        LeprecaunImageSources

        Attempts = 0;
        Successes = 0;
    end
    
    methods (Access = private)
        
        function makeAGuess(app, leprecaunIndex)
            app.Attempts = app.Attempts + 1;
            goldIndex = randi([1, 3]);

            app.ImageLeprecaun.Position = app.LeprecaunPositions(leprecaunIndex, :);
            app.ImageGold.Position = app.GoldPositions(goldIndex, :);
            
            if leprecaunIndex == goldIndex
                app.ImageLeprecaun.ImageSource = app.LeprecaunImageSources(1);
                app.Successes = app.Successes + 1;
            else
                app.ImageLeprecaun.ImageSource = app.LeprecaunImageSources(2);
            end

            app.LabelAttemptsValue.Text = string(app.Attempts);
            app.LabelSuccessValue.Text = string(app.Successes);

            app.LabelPercentValue.Text = string(app.Successes / app.Attempts * 100);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.LeprecaunImageSources = ["./Leprecaun Happy.png", "./Leprecaun Sad.png"];

            app.LeprecaunPositions(1, :) = [85, 230, 120, 120];
            app.LeprecaunPositions(2, :) = [264, 230, 120, 120];
            app.LeprecaunPositions(3, :) = [490, 230, 120, 120];

            app.GoldPositions(1, :) = [85, 45, 100, 100];
            app.GoldPositions(2, :) = [264, 45, 100, 100];
            app.GoldPositions(3, :) = [490, 45, 100, 100];
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.makeAGuess(1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.makeAGuess(2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.makeAGuess(3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GoldGuesserUIFigure = uifigure('Visible', 'off');
            app.GoldGuesserUIFigure.Name = 'Gold Guesser';
            app.Button_1 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.AttemptsLabel = uilabel(app.GoldGuesserUIFigure);
            app.AttemptsLabel.Text = 'Attempts: ';
            app.SuccessLabel = uilabel(app.GoldGuesserUIFigure);
            app.SuccessLabel.Text = 'Success:';
            app.PercentLabel = uilabel(app.GoldGuesserUIFigure);
            app.PercentLabel.Text = 'Percent:';
            app.LabelAttemptsValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelAttemptsValue.Text = '0';
            app.LabelSuccessValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelSuccessValue.Text = '0';
            app.LabelPercentValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelPercentValue.Text = '0';
            app.ImageLeprecaun = uiimage(app.GoldGuesserUIFigure);
            app.ImageLeprecaun.ImageSource = fullfile(pathToMLAPP, 'Leprecaun Sad.png');
            app.ImageGold = uiimage(app.GoldGuesserUIFigure);
            app.ImageGold.ImageSource = fullfile(pathToMLAPP, 'Pot of Gold.png');
            % Show the figure after all components are created
            app.GoldGuesserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoldGuesser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GoldGuesserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GoldGuesserUIFigure)
        end
    end
end