classdef LEDONOFF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        OFFButton      matlab.ui.control.Button
        ONButton       matlab.ui.control.Button
        LEDOnOffLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        SerialObj
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.SerialObj = serialport("COM3", 19200, "Timeout", 15);

        end

        % Button pushed function: ONButton
        function ONButtonPushed(app, event)
            fprintf("You pressed the ON button.\n");

            flush(app.SerialObj)
            writeline(app.SerialObj, "LED ON")
            response = readline(app.SerialObj)

        end

        % Button pushed function: OFFButton
        function OFFButtonPushed(app, event)
            fprintf("You pressed the OFF button.\n");

            flush(app.SerialObj)
            writeline(app.SerialObj, "LED OFF")
            response = readline(app.SerialObj)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDOnOffLabel = uilabel(app.UIFigure);
            app.LEDOnOffLabel.Text = 'LED On / Off';
            app.ONButton = uibutton(app.UIFigure, 'push');
            app.ONButton.ButtonPushedFcn = createCallbackFcn(app, @ONButtonPushed, true);
            app.ONButton.Text = 'ON';
            app.OFFButton = uibutton(app.UIFigure, 'push');
            app.OFFButton.ButtonPushedFcn = createCallbackFcn(app, @OFFButtonPushed, true);
            app.OFFButton.Text = 'OFF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LEDONOFF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end